classdef NPselectionPopUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1  matlab.ui.Figure
        text0    matlab.ui.control.Label
        btnBack  matlab.ui.control.Button
        btnDone  matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp 
    end
    
    properties (Access = public)
        chinese 
        index
        materialChange
    end
    
    methods (Access = private)
        function setCharacters(app)
                set(app.text0,'Text',lookUpLanguage('Screen Builder Setup',app.chinese));
                set(app.text1,'Text',sprintf('%s 1:', lookUpLanguage('Step',app.chinese)))
                set(app.text2,'Text',sprintf('%s 2:', lookUpLanguage('Step',app.chinese)));
                set(app.text3,'Text',sprintf('%s 3:', lookUpLanguage('Step',app.chinese)));
                set(app.btnDone,'Text',lookUpLanguage('Next',app.chinese));
                set(app.btnBack,'Text',lookUpLanguage('Back', app.chinese));
                set(app.figure1, 'Name', lookUpLanguage('Camber Measurement',app.chinese));
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function FourthScreen_OpeningFcn(app, mainApp, xLoc, cutWidth, index, yWidths, inChinese, inMatChange)
            app.chinese = inChinese;
            app.CallingApp = mainApp;
            loc = xLoc;
            width = cutWidth;
            app.index = index;
            widthVals = yWidths;
            setCharacters(app);
            app.materialChange = inMatChange;
            
            val = get(app.labelStepFour,'Text');
            val2 = get(app.labelStepFive,'Text');
            val3 = get(app.labelStepThree,'Text');
            val{1} = strcat(lookUpLanguage('Move gantry to:\nX = ',app.chinese),num2str(loc(2)),', Y=',num2str(width));
            val{2} = lookUpLanguage('Input Y value on Low Mag\nCamera Tick marks',app.chinese);
            val2{1} = strcat(lookUpLanguage('Move gantry to:\nX = ',app.chinese),num2str(loc(3)),', Y=',num2str(width),',   Z = -14.5');
            val2{2} = lookUpLanguage('Input Y value on Low Mag\nCamera Tick marks',app.chinese);
            val3{1} = strcat(lookUpLanguage('Move gantry to:\nX = ',app.chinese),num2str(loc(1)),', Y=',num2str(width));
            val3{2} = lookUpLanguage('Input Y value on Low Mag\nCamera Tick marks',app.chinese);
            set(app.labelStepFive,'Text',val2);
            set(app.labelStepFour,'Text',val);
            set(app.labelStepThree,'Text',val3);
            set(app.txtY1Val,'Value',num2str(widthVals(1)));
            set(app.txtY2Val,'Value',num2str(widthVals(2)));
            set(app.txtY3Val,'Value',num2str(widthVals(3)));
        end

        % Button pushed function: btnBack
        function btnBack_Callback(app, event)
            if(~app.materialChange)
                updateScreenBuilderAfterCamberMeasurePopUp(app.CallingApp, 0, 'Back')
            else
                updateMaterialChangeAfterCamberMeasure(app.CallingApp,0,'Back')
            end
            delete(app)
        end

        % Button pushed function: btnDone
        function btnDone_Callback(app, event)
            y1Loc =str2double(get(app.txtY1Val,'Value'));
            y2Loc =str2double(get(app.txtY2Val,'Value'));
            y3Loc =str2double(get(app.txtY3Val,'Value'));
            if(isnan(y1Loc))
                msgbox(sprintf('Step 5 Y \x8F74\x4F4D\x7F6E\x6709\x975E\x6570\x503C\x8F93\x5165 - A non numeric value was entered in the Step 5 Y location box'))
                return
            end
            if(isnan(y2Loc))
                msgbox(sprintf('Step 4 Y \x8F74\x4F4D\x7F6E\x6709\x975E\x6570\x503C\x8F93\x5165 - A non numeric value was entered in the Step 4 Y location box'))
                return
            end
            if(isnan(y3Loc))
                msgbox(sprintf('Step 3 Y \x8F74\x4F4D\x7F6E\x6709\x975E\x6570\x503C\x8F93\x5165 - A non numeric value was entered in the Step 3 Y location box'))
                return
            end
            if(~app.materialChange)
                updateScreenBuilderAfterCamberMeasurePopUp(app.CallingApp, app.index, [y1Loc, y2Loc, y3Loc]);
            else
                updateMaterialChangeAfterCamberMeasure(app.CallingApp, app.index, [y1Loc, y2Loc, y3Loc]);
            end
            delete(app);
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            updateScreenBuilderAfterTextPopUp(app.CallingApp, 'Exit')
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Camber Measurement';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.text0 = uilabel(app.figure1);
            app.text0.Text = '9 Points Measurements';
            app.btnBack = uibutton(app.figure1, 'push');
            app.btnBack.ButtonPushedFcn = createCallbackFcn(app, @btnBack_Callback, true);
            app.btnBack.Text = 'Back';
            app.btnDone = uibutton(app.figure1, 'push');
            app.btnDone.ButtonPushedFcn = createCallbackFcn(app, @btnDone_Callback, true);
            app.btnDone.Text = 'Next';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NPselectionPopUp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)FourthScreen_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end