classdef ScreenAnalysis_Main_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        DataFileSelectionLabel      matlab.ui.control.Label
        NameEditFieldLabel          matlab.ui.control.Label
        NameEditField               matlab.ui.control.EditField
        CaculateButton              matlab.ui.control.Button
        ClearAllButton              matlab.ui.control.Button
        Select_2DB                  matlab.ui.control.Button
        Select_Folder               matlab.ui.control.Button
        Select_3DRB                 matlab.ui.control.Button
        Select_3DRD                 matlab.ui.control.Button
        Select_3DLB                 matlab.ui.control.Button
        Select_3DLD                 matlab.ui.control.Button
        Edit_Folder                 matlab.ui.control.EditField
        Edit_2DB                    matlab.ui.control.EditField
        Edit_3DRB                   matlab.ui.control.EditField
        Edit_3DRD                   matlab.ui.control.EditField
        Edit_3DLB                   matlab.ui.control.EditField
        Edit_3DLD                   matlab.ui.control.EditField
        OptionsPanel                matlab.ui.container.Panel
        GaussianSliderLabel         matlab.ui.control.Label
        GaussianSlider              matlab.ui.control.Slider
        ImagesCheckBox              matlab.ui.control.CheckBox
        RightPanel                  matlab.ui.container.Panel
        SummaryTextAreaLabel        matlab.ui.control.Label
        SummaryTextArea             matlab.ui.control.TextArea
        UniformityAnalysisCheckBox  matlab.ui.control.CheckBox
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        % File dir/names
        fileDir
        file2D
        file3DRB
        file3DRD
        file3DLB
        file3DLD
        
        % Auditorium name
        Auditorium
        
        % Load 9 points data
        NP9
        
        % Gaussian smooth value
        smo
        
        % Output summary data
        MSG
    end
    
    properties (Access = private)
         % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Select_2DB
        function Select_2DBPushed(app, event)
            app.file2D=uigetfile([app.fileDir '*.csv;*.mat'],'Choose the 2D Bright Image.');
            app.Edit_2DB.Value=app.file2D(1:end-4);
        end

        % Button pushed function: Select_Folder
        function Select_FolderButtonPushed(app, event)
            app.fileDir=uigetdir('Choose Director to start with.');
            app.Edit_Folder.Value=app.fileDir;        
            app.fileDir=[app.fileDir '/']; 
        end

        % Value changed function: Edit_Folder
        function Edit_FolderValueChanged(app, event)
            value = app.Edit_Folder.Value;
            
        end

        % Button pushed function: Select_3DRB
        function Select_3DRBButtonPushed(app, event)
            app.file3DRB=uigetfile([app.fileDir '*.csv;*.mat'],'Choose the 3D-Right Eye Bright Image.');
            app.Edit_3DRB.Value=app.file3DRB(1:end-4);
        end

        % Button pushed function: Select_3DRD
        function Select_3DRDButtonPushed(app, event)
            app.file3DRD=uigetfile([app.fileDir '*.csv;*.mat'],'Choose the 3D-Right Eye Dark Image.');
            app.Edit_3DRD.Value=app.file3DRD(1:end-4);
        end

        % Button pushed function: Select_3DLB
        function Select_3DLBButtonPushed(app, event)
            app.file3DLB=uigetfile([app.fileDir '*.csv;*.mat'],'Choose the 3D-Left Eye Bright Image.');
            app.Edit_3DLB.Value=app.file3DLB(1:end-4); 
        end

        % Button pushed function: Select_3DLD
        function Select_3DLDButtonPushed(app, event)
            app.file3DLD=uigetfile([app.fileDir '*.csv;*.mat'],'Choose the 3D-Left Eye Dark Image.');
            fprintf(app.Edit_3DLD.Value)
            app.Edit_3DLD.Value=app.file3DLD(1:end-4);
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            app.Auditorium = app.NameEditField.Value;
            
        end

        % Callback function: CaculateButton, GaussianSlider
        function CaculateButtonPushed(app, event)
            Screen_Analysis(app);
            app.SummaryTextArea.Value= sprintf(app.MSG);
        end

        % Value changed function: SummaryTextArea
        function SummaryTextAreaValueChanged(app, event)
%             value = app.SummaryTextArea.Value;
            
        end

        % Value changed function: ImagesCheckBox
        function ImagesCheckBoxValueChanged(app, event)
            app.NP9 = app.ImagesCheckBox.Value;
            
        end

        % Value changed function: GaussianSlider
        function GaussianSliderValueChanged(app, event)
            app.smo = app.GaussianSlider.Value;
            
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.fileDir=''; app.Edit_Folder='';
            app.file2D='';  app.Edit_2DB='';
            app.file3DRB=''; app.Edit_3DRB='';
            app.file3DRD=''; app.Edit_3DRD='';
            app.file3DLB=''; app.Edit_3DLB='';
            app.file3DLD=''; app.Edit_3DLD='';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {408, 408};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {231, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DataFileSelectionLabel = uilabel(app.LeftPanel);
            app.DataFileSelectionLabel.Text = 'Data File Selection';
            app.NameEditFieldLabel = uilabel(app.LeftPanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.LeftPanel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.CaculateButton = uibutton(app.LeftPanel, 'push');
            app.CaculateButton.ButtonPushedFcn = createCallbackFcn(app, @CaculateButtonPushed, true);
            app.CaculateButton.Text = 'Caculate';
            app.ClearAllButton = uibutton(app.LeftPanel, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.Select_2DB = uibutton(app.LeftPanel, 'push');
            app.Select_2DB.ButtonPushedFcn = createCallbackFcn(app, @Select_2DBPushed, true);
            app.Select_2DB.Text = '2D Bright';
            app.Select_Folder = uibutton(app.LeftPanel, 'push');
            app.Select_Folder.ButtonPushedFcn = createCallbackFcn(app, @Select_FolderButtonPushed, true);
            app.Select_Folder.Text = 'Folder';
            app.Select_3DRB = uibutton(app.LeftPanel, 'push');
            app.Select_3DRB.ButtonPushedFcn = createCallbackFcn(app, @Select_3DRBButtonPushed, true);
            app.Select_3DRB.Text = '3D LKRW-Bright';
            app.Select_3DRD = uibutton(app.LeftPanel, 'push');
            app.Select_3DRD.ButtonPushedFcn = createCallbackFcn(app, @Select_3DRDButtonPushed, true);
            app.Select_3DRD.Text = '3D LKRW-Dark';
            app.Select_3DLB = uibutton(app.LeftPanel, 'push');
            app.Select_3DLB.ButtonPushedFcn = createCallbackFcn(app, @Select_3DLBButtonPushed, true);
            app.Select_3DLB.Text = '3D LWRK-Bright';
            app.Select_3DLD = uibutton(app.LeftPanel, 'push');
            app.Select_3DLD.ButtonPushedFcn = createCallbackFcn(app, @Select_3DLDButtonPushed, true);
            app.Select_3DLD.Text = '3D LWRK-Dark';
            app.Edit_Folder = uieditfield(app.LeftPanel, 'text');
            app.Edit_Folder.ValueChangedFcn = createCallbackFcn(app, @Edit_FolderValueChanged, true);
            app.Edit_2DB = uieditfield(app.LeftPanel, 'text');
            app.Edit_3DRB = uieditfield(app.LeftPanel, 'text');
            app.Edit_3DRD = uieditfield(app.LeftPanel, 'text');
            app.Edit_3DLB = uieditfield(app.LeftPanel, 'text');
            app.Edit_3DLD = uieditfield(app.LeftPanel, 'text');
            app.OptionsPanel = uipanel(app.LeftPanel);
            app.OptionsPanel.Title = 'Options';
            app.GaussianSliderLabel = uilabel(app.OptionsPanel);
            app.GaussianSliderLabel.Text = 'Gaussian';
            app.GaussianSlider = uislider(app.OptionsPanel);
            app.GaussianSlider.Limits = [0 160];
            app.GaussianSlider.ValueChangedFcn = createCallbackFcn(app, @GaussianSliderValueChanged, true);
            app.GaussianSlider.ValueChangingFcn = createCallbackFcn(app, @CaculateButtonPushed, true);
            app.GaussianSlider.Value = 64;
            app.ImagesCheckBox = uicheckbox(app.OptionsPanel);
            app.ImagesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImagesCheckBoxValueChanged, true);
            app.ImagesCheckBox.Text = '9 Images';
            app.RightPanel = uipanel(app.GridLayout);
            app.SummaryTextAreaLabel = uilabel(app.RightPanel);
            app.SummaryTextAreaLabel.Text = 'Summary';
            app.SummaryTextArea = uitextarea(app.RightPanel);
            app.SummaryTextArea.ValueChangedFcn = createCallbackFcn(app, @SummaryTextAreaValueChanged, true);
            app.UniformityAnalysisCheckBox = uicheckbox(app.RightPanel);
            app.UniformityAnalysisCheckBox.Text = 'Uniformity Analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ScreenAnalysis_Main_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end